classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure  matlab.ui.Figure
        WczytanieobrazuButton        matlab.ui.control.Button
        BinaryzacjametodOtsuButton   matlab.ui.control.Button
        BinaryzacjazprogiemadaptiveButton  matlab.ui.control.Button
        BinaryzacjazprogiemwasnymButton  matlab.ui.control.Button
        PrgwasnyEditFieldLabel       matlab.ui.control.Label
        PrgwasnyEditField            matlab.ui.control.NumericEditField
        Slider                       matlab.ui.control.Slider
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        MiejscenawczytanyobrazLabel  matlab.ui.control.Label
        MiejscenazbinaryzowanyobrazLabel  matlab.ui.control.Label
        NajpierwwybierzobrazLabel    matlab.ui.control.Label
        ZamianasygnauztemButton      matlab.ui.control.Button
        Sygna1ToLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        OriginalImage           double
        BinarizedImage          double
        ImageChosen             logical
        BinarizedImageGenerated logical
        SignalIsSwapped         logical
    end

    methods (Access = private)
    
        function result = binarizeWithOtsu(~, image)
            tmpImage = image;
            curr_k = -1;
            curr_W_w = 1000;
            
            for k = 0.01:0.02:1
                
                below = tmpImage(tmpImage<k);
                above = tmpImage(tmpImage>k);
                
                N = numel(tmpImage);
                N_0 = numel(below);
                N_1 = numel(above);
                
                S_0 = sum(below); 
                S_1 = sum(above);
                
                X_0 = S_0/N_0; % średnia jasność piksela w klasie
                X_1 = S_1/N_1;
                
                W_0 = (sum((below - X_0).^2)) / N_0;
                W_1 = (sum((above - X_1).^2)) / N_1;
                W_w = (N_0/N)*W_0 + (N_1/N)*W_1; % suma ważona wariancji wewnątrzklasowych
                
                if curr_k < 0 || W_w < curr_W_w
                    curr_W_w = W_w;
                    curr_k = k;
                end
            end
            
            tmpImage(tmpImage > curr_k) = 1;
            tmpImage(tmpImage <= curr_k) = 0;

%             app.PrgwasnyEditField.Value = curr_k; % TODO - wyswietl prog Otsu w GUI
%             app.Slider.Value = curr_k;

            result = tmpImage;
       	end

        function result = binarizeWithAdaptive(app, image)
%             tmpImage = image;
%             step = 70;
%             s = size(tmpImage);
%
%             if s(1) < step && s(2) < step
%                 result = binarizeWithOtsu(app, tmpImage);
%             else
%                 hx = floor(s(1)/2);
%                 hy = floor(s(2)/2);
%
%                 tmpImage(1:hx, 1:hy)     = binarizeWithAdaptive(app, tmpImage(1:hx, 1:hy));
%                 tmpImage(1:hx, hy:end)   = binarizeWithAdaptive(app, tmpImage(1:hx, hy:end));
%                 tmpImage(hx:end, 1:hy)   = binarizeWithAdaptive(app, tmpImage(hx:end,1:hy));
%                 tmpImage(hx:end, hy:end) = binarizeWithAdaptive(app, tmpImage(hx:end, hy:end));
%
%                 result = tmpImage;
%             end

%             squareSide = [70 70];
            squareSide = 2*floor(size(image)/16)+1; % wartosc domyslna dla matlaba
            squareSideHalf = floor(squareSide/2);

            [height, width, depth] = size(image);
            result = zeros(height, width);

            subimagePixelsCount = squareSideHalf(1) * squareSideHalf(2);

            for i = 1+squareSideHalf(1) : height-squareSideHalf(1)
                subimage = image(i-squareSideHalf(1):i-1, 1:squareSideHalf(2));
                suma = sum(sum(subimage));

                for j = 1+squareSideHalf(2) : width-squareSideHalf(2)
                    suma = suma + sum(image(i-squareSideHalf(1):i-1, j));
                    suma = suma - sum(image(i-squareSideHalf(1):i-1, j - squareSideHalf(2)));

                    threshold = suma / subimagePixelsCount;

                    if image(i, j) < threshold
                        result(i, j) = 0;
                    else
                        result(i, j) = 1;
                    end
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes, 'visible', 'off');
            set(app.UIAxes2, 'visible', 'off');
            set(app.UIAxes, 'xtick', []);
            set(app.UIAxes, 'ytick', []);
            set(app.UIAxes2, 'xtick', []);
            set(app.UIAxes2, 'ytick', []);
            app.NajpierwwybierzobrazLabel.Text = {''};
            app.ImageChosen = false;
            app.BinarizedImageGenerated = false;
            app.SignalIsSwapped = false;
        end

        % Button pushed function: BinaryzacjazprogiemadaptiveButton
        function binarizeWithAdaptiveThreshold(app, event)
            
            if app.ImageChosen == true
                if app.SignalIsSwapped == true
                    app.BinarizedImage = ~binarizeWithAdaptive(app, app.OriginalImage);
                else
                    app.BinarizedImage = binarizeWithAdaptive(app, app.OriginalImage);
                end
                
                app.MiejscenazbinaryzowanyobrazLabel.Text = {''; ''};
                app.BinarizedImageGenerated = true;
                imshow(app.BinarizedImage, 'parent', app.UIAxes2);
            else
                app.NajpierwwybierzobrazLabel.Text = {'Najpierw wybierz obraz'};
            end
        end

        % Button pushed function: BinaryzacjametodOtsuButton
        function binarizeWithOtsuThreshold(app, event)
        
            if app.ImageChosen == true
                if app.SignalIsSwapped == true
                    app.BinarizedImage = ~binarizeWithOtsu(app, app.OriginalImage);
                else
                    app.BinarizedImage = binarizeWithOtsu(app, app.OriginalImage);
                end
                
                app.MiejscenazbinaryzowanyobrazLabel.Text = {''; ''};
                app.BinarizedImageGenerated = true;
                imshow(app.BinarizedImage, 'parent', app.UIAxes2);
            else
                app.NajpierwwybierzobrazLabel.Text = {'Najpierw wybierz obraz'};
            end
        end

        % Button pushed function: BinaryzacjazprogiemwasnymButton
        function binarizeWithOwnThreshold(app, event)
            
            if app.ImageChosen == true
                tmpImage = app.OriginalImage;
                
                if app.SignalIsSwapped == false
                    tmpImage(app.OriginalImage > app.PrgwasnyEditField.Value) = 1;
                    tmpImage(app.OriginalImage <= app.PrgwasnyEditField.Value) = 0;
                else
                    tmpImage(app.OriginalImage <= app.PrgwasnyEditField.Value) = 1;
                    tmpImage(app.OriginalImage > app.PrgwasnyEditField.Value) = 0;
                end
                
                app.BinarizedImage = tmpImage;
                
                app.MiejscenazbinaryzowanyobrazLabel.Text = {''; ''};
                app.BinarizedImageGenerated = true;
                imshow(app.BinarizedImage, 'parent', app.UIAxes2);
            else
                app.NajpierwwybierzobrazLabel.Text = {'Najpierw wybierz obraz'};
            end
        end

        % Button pushed function: WczytanieobrazuButton
        function loadImage(app, event)
            pause on;
            [file, path] = uigetfile('*.jpg; *.png; *.jpeg; *.PNG; *.JPEG; *.jpeg', 'Wybierz obraz do zbinaryzowania');
            
            if ~isequal(file, 0)
                app.ImageChosen = true;
                app.BinarizedImageGenerated = false;
                app.SignalIsSwapped = false;
                app.Sygna1ToLabel.Text = {'0 - Sygnał'; '1 - Tło'};
                app.NajpierwwybierzobrazLabel.Text = {''};
                
                pathToFile = fullfile(path, file);
                app.OriginalImage = rgb2gray(double(imread(pathToFile)) / 255);
                app.MiejscenawczytanyobrazLabel.Text = {''; ''};
                cla(app.UIAxes2);
                pause(0.4);
                app.MiejscenazbinaryzowanyobrazLabel.Text = {'Miejsce na'; 'zbinaryzowany obraz'};
                imshow(app.OriginalImage, 'parent', app.UIAxes);
            end
        end

        % Button pushed function: ZamianasygnauztemButton
        function swapSignalWithBackground(app, event)
            if app.BinarizedImageGenerated == true
                app.Sygna1ToLabel.Text = {strcat('0' + ~app.SignalIsSwapped, ' - Sygnał'); strcat( '0' + app.SignalIsSwapped, ' - Tło')};
                app.SignalIsSwapped = ~app.SignalIsSwapped;
                app.BinarizedImage = ~app.BinarizedImage;
                imshow(app.BinarizedImage, 'parent', app.UIAxes2);
            else
                app.NajpierwwybierzobrazLabel.Text = {'Najpierw zbinaryzuj obraz'};
            end
        end

        % Callback function
        function updateNumericField(app, event)
            app.PrgwasnyEditField.Value = app.Slider.Value;
        end

        % Value changed function: PrgwasnyEditField
        function updateSliderValue(app, event)
            app.Slider.Value = app.PrgwasnyEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure = uifigure;
            app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure.Name = 'Binaryzacja - Marek Kiełtyka, Michał Leszczyński';
            app.WczytanieobrazuButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.WczytanieobrazuButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.WczytanieobrazuButton.Text = 'Wczytanie obrazu';
            app.BinaryzacjametodOtsuButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjametodOtsuButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithOtsuThreshold, true);
            app.BinaryzacjametodOtsuButton.Text = {'Binaryzacja'; 'metodą Otsu'};
            app.BinaryzacjazprogiemadaptiveButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjazprogiemadaptiveButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithAdaptiveThreshold, true);
            app.BinaryzacjazprogiemadaptiveButton.Text = {'Binaryzacja'; 'z progiem adaptive'};
            app.BinaryzacjazprogiemwasnymButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjazprogiemwasnymButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithOwnThreshold, true);
            app.BinaryzacjazprogiemwasnymButton.Text = {'Binaryzacja'; 'z progiem własnym'};
            app.PrgwasnyEditFieldLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.PrgwasnyEditFieldLabel.Text = 'Próg własny';
            app.PrgwasnyEditField = uieditfield(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'numeric');
            app.PrgwasnyEditField.ValueChangedFcn = createCallbackFcn(app, @updateSliderValue, true);
            app.PrgwasnyEditField.Limits = [0 1];
            app.PrgwasnyEditField.ValueDisplayFormat = '%.3f';
            app.Slider = uislider(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @updateNumericField, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @updateNumericField, true);
            app.UIAxes = uiaxes(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.UIAxes2 = uiaxes(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenawczytanyobrazLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenawczytanyobrazLabel.Text = {'Miejsce na'; 'wczytany obraz'};
            app.MiejscenazbinaryzowanyobrazLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenazbinaryzowanyobrazLabel.Text = {'Miejsce na'; 'zbinaryzowany obraz'};
            app.NajpierwwybierzobrazLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.NajpierwwybierzobrazLabel.Text = 'Najpierw wybierz obraz';
            app.ZamianasygnauztemButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.ZamianasygnauztemButton.ButtonPushedFcn = createCallbackFcn(app, @swapSignalWithBackground, true);
            app.ZamianasygnauztemButton.Text = 'Zamiana sygnału z tłem';
            app.Sygna1ToLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.Sygna1ToLabel.Text = {'0 - Sygnał'; '1 - Tło'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = projekt

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure)
        end
    end
end